classdef etchasketch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        main_Panel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        ShakeButton         matlab.ui.control.Button
        XKnobLabel          matlab.ui.control.Label
        XKnob               matlab.ui.control.Knob
        YKnobLabel          matlab.ui.control.Label
        YKnob               matlab.ui.control.Knob
        ColorDropDownLabel  matlab.ui.control.Label
        ColorDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            plot(app.UIAxes,app.XKnob.Value,app.YKnob.Value,...
                'linewidth',2,'color',[.2 .2 .2]);
            xlim(app.UIAxes,[0 100]);
            ylim(app.UIAxes,[0 100]);
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.UIAxes.Children(1).Color=value;
        end

        % Button pushed function: ShakeButton
        function ShakeButtonPushed(app, event)
            img=app.UIAxes.Children(1);
            mybeep=randn(15000,1);
            p=audioplayer(mybeep,60000);
            ocolor=img.Color;
            for n=1:3
                play(p);
                img.Color=mean([img.Color;app.UIAxes.BackgroundColor]);
                pause(.4)
            end
            img.YData=nan;
            img.XData=nan;
            img.Color=ocolor;
        end

        % Value changing function: XKnob
        function XKnobValueChanging(app, event)
            changingValue = event.Value;
            img = app.UIAxes.Children(1);
            img.YData(end+1)=app.YKnob.Value;
            img.XData(end+1)=changingValue;
        end

        % Value changing function: YKnob
        function YKnobValueChanging(app, event)
            changingValue = event.Value;
            img = app.UIAxes.Children(1);
            img.XData(end+1)=app.XKnob.Value;
            img.YData(end+1)=changingValue;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.main_Panel = uipanel(app.UIFigure);
            app.main_Panel.Title = 'etchasketch';
            app.UIAxes = uiaxes(app.main_Panel);
            title(app.UIAxes, 'Etch-A-Sketch')
            app.ShakeButton = uibutton(app.main_Panel, 'push');
            app.ShakeButton.ButtonPushedFcn = createCallbackFcn(app, @ShakeButtonPushed, true);
            app.ShakeButton.Text = 'Shake';
            app.XKnobLabel = uilabel(app.main_Panel);
            app.XKnobLabel.Text = 'X';
            app.XKnob = uiknob(app.main_Panel, 'continuous');
            app.XKnob.ValueChangingFcn = createCallbackFcn(app, @XKnobValueChanging, true);
            app.XKnob.Value = 50;
            app.YKnobLabel = uilabel(app.main_Panel);
            app.YKnobLabel.Text = 'Y';
            app.YKnob = uiknob(app.main_Panel, 'continuous');
            app.YKnob.ValueChangingFcn = createCallbackFcn(app, @YKnobValueChanging, true);
            app.YKnob.Value = 50;
            app.ColorDropDownLabel = uilabel(app.main_Panel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.main_Panel);
            app.ColorDropDown.Items = {'Black', 'Red', 'Blue', 'Green'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Black';
        end
    end

    methods (Access = public)

        % Construct app
        function app = etchasketch

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end