classdef pingpong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        main_Panel             matlab.ui.container.Panel
        GoButton               matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        Slider                 matlab.ui.control.Slider
        LivesEditFieldLabel    matlab.ui.control.Label
        LivesEditField         matlab.ui.control.NumericEditField
        BallSpeedGaugeLabel    matlab.ui.control.Label
        BallSpeedGauge         matlab.ui.control.SemicircularGauge
        BouncesEditFieldLabel  matlab.ui.control.Label
        BouncesEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        paddle % Center of paddle
        ball % Description
        field % Description
    end

    methods (Access = private)
    
        function results = timestep(app)
            newx = app.ball.x + app.ball.dx;
            newy = app.ball.y + app.ball.dy;
            results = 'free flight';

            if newx < app.field.WallX
                app.ball.dx = -app.ball.dx;
                newx = app.field.WallX;
                app.ball.dy = app.ball.dy+randn(1,1)*.5;
                results = 'bounce';
            end
            
            if newy > app.field.Upper
                app.ball.dy = -app.ball.dy;
                app.ball.dx = app.ball.dx+randn(1,1)*.5;
                newy = app.field.Upper;
                results = 'bounce';
            end
            
            if newy < app.field.Lower
                app.ball.dy = -app.ball.dy;
                app.ball.dx = app.ball.dx+randn(1,1)*.5;
                newy = app.field.Lower;
                results = 'bounce';
            end
            
            if newx > app.paddle.x
                if (newy > app.paddle.bottom && newy < app.paddle.top)
                    app.ball.dx = -app.ball.dx;
                    newx = app.paddle.x;
                    results = 'paddle';
                else
                    results = 'score';
                end
                    
            end
                
            ball=findobj(app.UIAxes,'Tag','ball');
            app.ball.x=newx;
            app.ball.y=newy;
            set(ball,'XData',newx,'YData',newy);
            pause(.05)
        end
        
        function initalize_ball(app)
            app.ball.dx = -2;
            app.ball.dy = .2;
            app.ball.x = 20;
            app.ball.y = 20;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize ball
            app.initalize_ball();
            
            % initialize field
            app.field.Upper = 40;
            app.field.Lower = 0;
            app.field.WallX = 0;
            app.field.FreeX = 40;
            app.UIAxes.XLim=[app.field.WallX app.field.FreeX];
            app.UIAxes.YLim=[app.field.Lower app.field.Upper];
            
            % initalize paddle
            middle = (app.field.Upper + app.field.Lower) / 2;
            height = 10;
            app.paddle.halfheight = height /2;
            app.paddle.middle=middle;
            app.paddle.top = middle + app.paddle.halfheight;
            app.paddle.bottom = middle - app.paddle.halfheight;
            app.paddle.width = 5;
            app.paddle.x = 38;


            % populate field
            plot(app.UIAxes,app.ball.x,app.ball.y,'o','tag','ball','linewidth',5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[app.paddle.x, app.paddle.x],...
                [app.paddle.bottom, app.paddle.top],...
                'ks-','linewidth',app.paddle.width,'tag','paddle');
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            while app.LivesEditField.Value>0
                app.initalize_ball();
                r = app.timestep;
                while ~strcmp(r,'score')
                    r = app.timestep();
                    if ~strcmp(r,'free flight')
                        disp(r)
                        app.BallSpeedGauge.Value=sqrt(app.ball.dx.^2 + app.ball.dy.^2);
                    end
                    pause(.01);
                    
                    if strcmp(r,'bounce')
                        app.BouncesEditField.Value = app.BouncesEditField.Value+1;
                        if mod(app.BouncesEditField.Value,5)==4
                            app.ball.dx=app.ball.dx.*1.1;
                            app.ball.dy=app.ball.dy.*1.1;
                        end
                    end
                    
                end
                pause(1);
                app.BallSpeedGauge.Value=0;
                app.LivesEditField.Value=app.LivesEditField.Value-1;
            end
            
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            h = findobj(app.UIAxes,'Tag','paddle');
            app.paddle.bottom=changingValue-app.paddle.halfheight;
            app.paddle.top=changingValue+app.paddle.halfheight;
            set(h,'YData',[app.paddle.bottom, app.paddle.top])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.main_Panel = uipanel(app.UIFigure);
            app.main_Panel.Title = 'Pingpong';
            app.GoButton = uibutton(app.main_Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UIAxes = uiaxes(app.main_Panel);
            title(app.UIAxes, 'Pong')
            app.Slider = uislider(app.main_Panel);
            app.Slider.Limits = [0 40];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 20;
            app.LivesEditFieldLabel = uilabel(app.main_Panel);
            app.LivesEditFieldLabel.Text = 'Lives';
            app.LivesEditField = uieditfield(app.main_Panel, 'numeric');
            app.LivesEditField.Limits = [0 Inf];
            app.LivesEditField.Value = 3;
            app.BallSpeedGaugeLabel = uilabel(app.main_Panel);
            app.BallSpeedGaugeLabel.Text = 'Ball Speed';
            app.BallSpeedGauge = uigauge(app.main_Panel, 'semicircular');
            app.BallSpeedGauge.Limits = [0 5];
            app.BouncesEditFieldLabel = uilabel(app.main_Panel);
            app.BouncesEditFieldLabel.Text = 'Bounces';
            app.BouncesEditField = uieditfield(app.main_Panel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = pingpong

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end