classdef mlAppKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        BuildButton                    matlab.ui.control.Button
        BuildyourExistingProjectLabel  matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        PackagethemFilesLabel          matlab.ui.control.Label
        PackageButton                  matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        runthepackageLabel             matlab.ui.control.Label
        RunPackageButton               matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        DirectoryEditFieldLabel        matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        selecttheprojectdirectoryortypeitLabel  matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        mlAppKitDashboardLabel         matlab.ui.control.Label
        Description                    matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        runtheQucikStartWizardLabel    matlab.ui.control.Label
        WizardButton                   matlab.ui.control.Button
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end

    methods (Access = public)
    
        function results = mlappkit(app, project_path)
            current_path= pwd();
            cd(project_path)
            buildTheApp()
            cd
            cd(current_path)
            
            results = 0;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % reshape and center the current applucation window
           %--- Center window and apply target resultion
            value = '1024x640'; % target resolution
            
            xPos = regexp(value,'x');
            d1 = str2double( value(  1     : xPos-1 )) ;
            d2 = str2double( value( xPos+1 : end    )) ;
            st = get(0,'ScreenSize');
            
            xDif = (st(3)-d1)/2;
            yDif = (st(4)-d2)/2;
            
            nSize=[xDif, yDif, d1, d2];
            app.UIFigure.Position = nSize;
            n2Size=[1,1,1024,640];
            %--- Center window and apply target resultion
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selpath = uigetdir;
            app.DirectoryEditField.Value = selpath;
        end

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            cwd = app.DirectoryEditField.Value;
            cd(cwd)
            buildTheApp()
        end

        % Button pushed function: WizardButton
        function WizardButtonPushed(app, event)
            mlappkit_quickstart()
        end

        % Button pushed function: PackageButton
        function PackageButtonPushed(app, event)
            package_app()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.BuildButton = uibutton(app.Panel_2, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.BuildyourExistingProjectLabel = uilabel(app.Panel_2);
            app.BuildyourExistingProjectLabel.Text = 'Build your Existing Project';
            app.Panel_3 = uipanel(app.Panel);
            app.PackagethemFilesLabel = uilabel(app.Panel_3);
            app.PackagethemFilesLabel.Text = 'Package the m-Files';
            app.PackageButton = uibutton(app.Panel_3, 'push');
            app.PackageButton.ButtonPushedFcn = createCallbackFcn(app, @PackageButtonPushed, true);
            app.PackageButton.Text = 'Package';
            app.Panel_4 = uipanel(app.Panel);
            app.runthepackageLabel = uilabel(app.Panel_4);
            app.runthepackageLabel.Text = 'run the package';
            app.RunPackageButton = uibutton(app.Panel_4, 'push');
            app.RunPackageButton.Text = 'Run Package';
            app.Panel_5 = uipanel(app.Panel);
            app.DirectoryEditFieldLabel = uilabel(app.Panel_5);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.Panel_5, 'text');
            app.selecttheprojectdirectoryortypeitLabel = uilabel(app.Panel_5);
            app.selecttheprojectdirectoryortypeitLabel.Text = 'select the project directory or type it';
            app.BrowseButton = uibutton(app.Panel_5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.mlAppKitDashboardLabel = uilabel(app.Panel);
            app.mlAppKitDashboardLabel.Text = 'ml App Kit - Dashboard';
            app.Description = uilabel(app.Panel);
            app.Description.Text = {'keep it open notice that for certain operations you'; 'need to be in the project directory'};
            app.Panel_6 = uipanel(app.Panel);
            app.runtheQucikStartWizardLabel = uilabel(app.Panel_6);
            app.runtheQucikStartWizardLabel.Text = 'run the Qucik-Start Wizard';
            app.WizardButton = uibutton(app.Panel_6, 'push');
            app.WizardButton.ButtonPushedFcn = createCallbackFcn(app, @WizardButtonPushed, true);
            app.WizardButton.Text = 'Wizard';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mlAppKit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end