classdef host_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uif                  matlab.ui.Figure
        HomeMenu             matlab.ui.container.Menu
        firstPluginMenu      matlab.ui.container.Menu
        bigredoneMenu        matlab.ui.container.Menu
        secondbigredoneMenu  matlab.ui.container.Menu
        smallredoneMenu      matlab.ui.container.Menu
        HelpMenu             matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        SettingsMenu         matlab.ui.container.Menu
        DocumentationMenu    matlab.ui.container.Menu
        LicenceMenu          matlab.ui.container.Menu
        HomePanel            matlab.ui.container.Panel
        MessageTextArea      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
    %% the properties of the :class:`host_app` are only declared
    % They could serve as:
    % :in-app database: keep data in RAM avoid Disk I/O   via `struct()` 
    % :plugins:  `struct()` holding views 
    % 
    
        m1home   % plugin, one placeholder is left for in-*host*-app tabs, but could be modified to be loaded externally aswell
                 % I kept it to serve as some kind of landing/404/503 equivalent
                 % for example: a dashboard with charts and news could be set up
        
        firstplugin   % plugin, for firstplugin carrying the red buttons
        
   
        help     % plugin, placeholder for in-app about, help/documentation, license,  web links, etc...
    
        % m4_calculate % plugin
        %
        % :todo: menu entries must be the same as the TITLE of the panel in the class ?
        % 
        % :Note: instead of bloating the number of elements like I did in my first apps
        %        I turned to create properties as placeholders for the windows or views to be loaded.
        %        *advantages*: maintainability
        %        *drawbacks*: complexity
        % 
    end

    methods (Access = public)
    
        function r = dummyPublicMethod(app)
            %% a dummy public mehtod
            %
            % :props app: handle
            % :type app: :class:`matlab.apps.AppBase`
            %
            
            % do something meaningful like developing 
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% The :func:`startupFnc`    is used to:
        %   + intatiate the plugins
        %   + assign appropriate tags to the plugins
        %   + and resize the window and center it on the user screen
        % 
        % :param app: a reference to itself
        % :type app: :class: `matlab.apps.AppBase`
        % 
        % create all subpanels from different m-filers
        %
        % :todo: mandatory name `app.uif` for UIFigure of host app? (app.uif)
        %
        % :todo: autogenerate the connections 
        %
            app.firstplugin.bigredone           =  bigredone(app.uif);        %
            app.firstplugin.secondbigredone     =  secondbigredone(app.uif);        %
            app.firstplugin.smallredone         =  smallredone(app.uif);        %
        %
        % :todo: mandatory name  `main_Panel` for UIPanel  of plugins? (main_Panel)
        %            
            
       % set visibility to "off" by default
            app.firstplugin.bigredone.main_Panel.Visible       = 'off'; %
            app.firstplugin.secondbigredone.main_Panel.Visible          = 'off'; %
            app.firstplugin.smallredone.main_Panel.Visible          = 'off'; %
            app.HomePanel.Visible                       = 'on';
           
        %
        % :todo: mandatory tag names  of plugins? Way to automate them ? 
        %           
        
       % assign tags
            app.firstplugin.bigredone.main_Panel.Tag         = 'bigredone'; %
            app.firstplugin.secondbigredone.main_Panel.Tag   = 'secondbigredone';    %
            app.firstplugin.smallredone.main_Panel.Tag       = 'smallredone';    %
            %----
            app.HomePanel.Tag                    = 'home';

        
            %--- Center window and apply target resultion
            value = '1024x640'; % target resolution
            
            xPos = regexp(value,'x');
            d1 = str2double( value(  1     : xPos-1 )) ;
            d2 = str2double( value( xPos+1 : end    )) ;
            
            % center window
            st = get(0,'ScreenSize');
            
            xDif = (st(3)-d1)/2;
            yDif = (st(4)-d2)/2;
            
            nSize=[xDif, yDif, d1, d2];
            app.uif.Position = nSize;
            n2Size=[1,1,1024,640];
            %--- Center window and apply target resultion
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            % change the view to the landing / home / neutral page 
            target_tag='home';
            panel_visibility_switch(app,target_tag);
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            % firstplugin a pop-up :class:`popup_settings`
            d=popup_settings; % new ui 
        end

        % Menu selected function: bigredoneMenu
        function bigredoneMenuSelected(app, event)
            % switch the view
            target_tag='bigredone';
            panel_visibility_switch(app,target_tag)
        end

        % Menu selected function: secondbigredoneMenu
        function secondbigredoneMenuSelected(app, event)
            % switch the view
            % 
            % see also: :ref:`panel_visibility_switch() <panel_visibility>`
            %
            target_tag='secondbigredone';
            panel_visibility_switch(app,target_tag)
        end

        % Menu selected function: smallredoneMenu
        function smallredoneMenuSelected(app, event)
            target_tag='smallredone';
            panel_visibility_switch(app,target_tag)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % firstplugin   :class:`popup_about`
            d=popup_about;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uif = uifigure;
            app.uif.Name = 'mlAppBinder Boilerplate';
            app.HomeMenu = uimenu(app.uif);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.firstPluginMenu = uimenu(app.uif);
            app.firstPluginMenu.Text = 'Red Buttons';
            app.bigredoneMenu = uimenu(app.firstPluginMenu);
            app.bigredoneMenu.MenuSelectedFcn = createCallbackFcn(app, @bigredoneMenuSelected, true);
            app.bigredoneMenu.Text = 'Red Button 1';
            app.secondbigredoneMenu = uimenu(app.firstPluginMenu);
            app.secondbigredoneMenu.MenuSelectedFcn = createCallbackFcn(app, @secondbigredoneMenuSelected, true);
            app.secondbigredoneMenu.Text = 'Red Button 2';
            app.smallredoneMenu = uimenu(app.firstPluginMenu);
            app.smallredoneMenu.MenuSelectedFcn = createCallbackFcn(app, @smallredoneMenuSelected, true);
            app.smallredoneMenu.Text = 'Red Button 3';
            app.HelpMenu = uimenu(app.uif);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SettingsMenu = uimenu(app.HelpMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.LicenceMenu = uimenu(app.HelpMenu);
            app.LicenceMenu.Text = 'Licence';
            app.HomePanel = uipanel(app.uif);
            app.HomePanel.Title = 'Home';
            app.MessageTextArea = uitextarea(app.HomePanel);
            app.MessageTextArea.Value = {''; 'option for static or dynamic welcome content'; 'but could be removed'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = host_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uif)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uif)
        end
    end
end