classdef popup_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uifpopup               matlab.ui.Figure
        SettingsPopUpPanel     matlab.ui.container.Panel
        EditButton             matlab.ui.control.Button
        EditsomesettingsLabel  matlab.ui.control.Label
        CallButton             matlab.ui.control.Button
        CallforaUNSecurityCouncilMeetingLabel  matlab.ui.control.Label
        Label                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
        if exist('settings','dir') ~=7
            mkdir settings
        else
        
        end
        
        if ~exist('settings/meshuggeServicesAPI.txt','file')
            fid= fopen('settings/meshuggeServicesAPI.txt','w');
            fclose all;
            
            open('settings/meshuggeServicesAPI.txt');
            
        else
            
            open('settings/meshuggeServicesAPI.txt');
             
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uifpopup = uifigure('Visible', 'off');
            app.uifpopup.Name = 'Settings';
            app.SettingsPopUpPanel = uipanel(app.uifpopup);
            app.SettingsPopUpPanel.Title = 'Settings PopUp ';
            app.EditButton = uibutton(app.SettingsPopUpPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.EditsomesettingsLabel = uilabel(app.SettingsPopUpPanel);
            app.EditsomesettingsLabel.Text = 'Edit some settings';
            app.CallButton = uibutton(app.SettingsPopUpPanel, 'push');
            app.CallButton.Text = 'Call';
            app.CallforaUNSecurityCouncilMeetingLabel = uilabel(app.SettingsPopUpPanel);
            app.CallforaUNSecurityCouncilMeetingLabel.Text = {'Call for a UN Security'; ' Council Meeting'};
            app.Label = uilabel(app.SettingsPopUpPanel);
            app.Label.Text = {'This is  an example for a external app'; 'which might be related to the main app.'; ''; 'However, at some point we realized that'; 'the functionality is not essential and was'; 'moved to an external app to save'; 'some real estate.'};
            % Show the figure after all components are created
            app.uifpopup.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popup_settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uifpopup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uifpopup)
        end
    end
end